classdef RadarTestGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        TrackingLampLabel          matlab.ui.control.Label
        TrackingLamp               matlab.ui.control.Lamp
        AquistionLampLabel         matlab.ui.control.Label
        AquistionLamp              matlab.ui.control.Lamp
        TargetAngleEditFieldLabel  matlab.ui.control.Label
        TargetAngleEditField       matlab.ui.control.NumericEditField
        IterationEditFieldLabel    matlab.ui.control.Label
        IterationEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        S=0% initialization of Start Stop button state
    end
    
    
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %             Phase Difference Measurement             %
        %              with MATLAB Implementation              %
        %                                                      %
        % Author: M.Sc. Eng. Hristo Zhivomirov        12/01/14 %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function PhDiff = phdiffmeasure(x, y)
            % function: PhDiff = phdiffmeasure(x, y)
            % x - first signal in the time domain
            % y - second signal in the time domain
            % PhDiff - phase difference Y -> X, rad
            % represent x as column-vector if it is not
            if size(x, 2) > 1
                x = x';
            end
            % represent y as column-vector if it is not
            if size(y, 2) > 1
                y = y';
            end
            % remove the DC component
            x = x - mean(x);
            y = y - mean(y);
            % signals length
            N = 4000;
            % window preparation
            win = rectwin(N);
            % fft of the first signal
            X = fft(x.*win);
            % fft of the second signal
            Y = fft(y.*win);
            % phase difference calculation
            [~, indx] = max(abs(X));
            [~, indy] = max(abs(Y));
            PhDiff = angle(Y(indy)) - angle(X(indx));
        end
    end
    

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            commandStr = 'python start.py';
            system(commandStr);
            i=0;
            app.S = 1
            while true
                app.IterationEditField.Value=i
                %Insert Loop Statements
                commandStr = 'python firebaseDownloadMode.py';
                system(commandStr);
                fileID = fopen('outputStatus.csv');
                a=textscan(fileID,'%s');
                
                b=cell2table(a);
                c=table2array(b);
                fclose(fileID);
                drawnow()
                if (c=="Start")
                    if(i>60)
                        disp(['Cannot communicate with PI'])
                        commandStr = 'python stop.py';
                        system(commandStr);
                        break
                    end
                    i=i+1;
                end
                
                if(c=="Acquisition")
                    app.AquistionLamp.Color = [0 1 0];
                    app.TrackingLamp.Color = [0.902 0.902 0.902];
                    i=i+1;
                end
                
                
                if(c=="Tracking")
                    app.TrackingLamp.Color = [0 1 0];
                    app.AquistionLamp.Color = [0.902 0.902 0.902];
                    commandStr = 'python Download.py';
                    system(commandStr);
                    xsampl=csvread('output1.csv');
                    x = transpose(xsampl);
                    ysampl=csvread('output2.csv');
                    y = transpose(ysampl);
                    t=[0:3999];
                    sum=x+y;
                    diff=x-y;
                    % phase difference calculation
                    PhDiff = phdiffmeasure(sum, diff);
                    PhDiff = PhDiff*180/pi;
                    % display the phase difference
                    PhDiffstr = num2str(PhDiff);
%                     disp(['Phase difference Y->X = ' PhDiffstr ' deg'])
                    localPythonCmd = sprintf('python postLocal.py %f',PhDiff);
                    commandStr = localPythonCmd;
                    system(commandStr);
                    i=i+1;
                    drawnow()
                end
                if(c=="Local")
                    i=i+1;
                end
              
                num = num2str(i);
%                 disp(['Iteration = ' num ])
                if (app.S==0)
                    app.AquistionLamp.Color = [0.902 0.902 0.902];
                    app.TrackingLamp.Color = [0.902 0.902 0.902];
                    break;
                end
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        commandStr = 'python stop.py';
        system(commandStr);
        app.S =0
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TrackingLampLabel = uilabel(app.UIFigure);
            app.TrackingLampLabel.Text = 'Tracking';
            app.TrackingLamp = uilamp(app.UIFigure);
            app.AquistionLampLabel = uilabel(app.UIFigure);
            app.AquistionLampLabel.Text = 'Aquistion';
            app.AquistionLamp = uilamp(app.UIFigure);
            app.TargetAngleEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAngleEditFieldLabel.Text = 'Target Angle';
            app.TargetAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationEditFieldLabel = uilabel(app.UIFigure);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationEditField.Editable = 'off';
            app.IterationEditField.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RadarTestGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end